classdef Crop_video_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CropButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ROI
        V
        outpath
        m
        matpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, m, V, outpath, matpath)
            app.m=m;            
            imagesc(app.m.Cn,'parent',app.UIAxes)
            app.ROI = drawrectangle(app.UIAxes);
            app.V=V;
            app.outpath=outpath;
           app.matpath=matpath;
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            ver=round(app.ROI.Vertices);
            app.m.Cn=app.m.Cn(ver(1,2):ver(2,2),ver(2,1):ver(3,1));
            app.m.Cn_all=app.m.Cn_all(ver(1,2):ver(2,2),ver(2,1):ver(3,1),:);
            app.m.PNR=app.m.PNR(ver(1,2):ver(2,2),ver(2,1):ver(3,1));
            app.m.PNR_all=app.m.PNR_all(ver(1,2):ver(2,2),ver(2,1):ver(3,1),:);
            imagesc(app.m.Cn,'parent',app.UIAxes)
            Y=app.V(ver(1,2):ver(2,2),ver(2,1):ver(3,1),:);
            saveash5(Y,app.outpath);
            [filepath,name]=fileparts(app.outpath);
            out=strcat(filepath,'\',name,'.mat');  
            m=app.m;      
            save(out,'-struct','m');
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crop_video_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end